classdef PPPH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Main                            matlab.ui.Figure
        LoadSavedConfigurationButton    matlab.ui.control.Button
        ToEditField                     matlab.ui.control.NumericEditField
        ToEditFieldLabel                matlab.ui.control.Label
        FromEditField                   matlab.ui.control.NumericEditField
        FromEditFieldLabel              matlab.ui.control.Label
        EpochIntervalsecondofdayLabel   matlab.ui.control.Label
        DefaultConfigurationButton      matlab.ui.control.Button
        SaveConfigurationButton         matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        MainLabel                       matlab.ui.control.Label
        Tabs                            matlab.ui.container.TabGroup
        Input                           matlab.ui.container.Tab
        secLabel                        matlab.ui.control.Label
        OrbitFilesForPanel              matlab.ui.container.Panel
        Orbit_A                         matlab.ui.control.EditField
        NextdayLabel                    matlab.ui.control.Label
        OrbitButton_A                   matlab.ui.control.Button
        Orbit_B                         matlab.ui.control.EditField
        PreviousdayLabel                matlab.ui.control.Label
        OrbitButton_B                   matlab.ui.control.Button
        Orbit                           matlab.ui.control.EditField
        ObservationdayLabel             matlab.ui.control.Label
        OrbitButton                     matlab.ui.control.Button
        ClockFileIntervalEditField      matlab.ui.control.NumericEditField
        ClockFileIntervalEditFieldLabel  matlab.ui.control.Label
        BEIDOUCheckBox                  matlab.ui.control.CheckBox
        GALILEOCheckBox                 matlab.ui.control.CheckBox
        GLONASSCheckBox                 matlab.ui.control.CheckBox
        GPSCheckBox                     matlab.ui.control.CheckBox
        SystemSelectionLabel            matlab.ui.control.Label
        DCBDropDown                     matlab.ui.control.DropDown
        DCBLabel                        matlab.ui.control.Label
        DCB                             matlab.ui.control.EditField
        DCBFileLabel                    matlab.ui.control.Label
        DCBButton                       matlab.ui.control.Button
        SavedFileButton                 matlab.ui.control.Button
        SavedFile                       matlab.ui.control.EditField
        ChoosesavedfileLabel            matlab.ui.control.Label
        SavedFileCB                     matlab.ui.control.CheckBox
        ImportButton                    matlab.ui.control.Button
        SaveAsButton                    matlab.ui.control.Button
        AntennaButton                   matlab.ui.control.Button
        ClockButton                     matlab.ui.control.Button
        ObservationButton               matlab.ui.control.Button
        Antenna                         matlab.ui.control.EditField
        AntennaFileEditFieldLabel       matlab.ui.control.Label
        Clock                           matlab.ui.control.EditField
        ClockFileLabel                  matlab.ui.control.Label
        ClockDropDown                   matlab.ui.control.DropDown
        ClockSourceLabel                matlab.ui.control.Label
        Observation                     matlab.ui.control.EditField
        ObservationFileLabel            matlab.ui.control.Label
        Preprocess                      matlab.ui.container.Tab
        degreeLabel                     matlab.ui.control.Label
        ProcessingModeButtonGroup       matlab.ui.container.ButtonGroup
        KinematicButton                 matlab.ui.control.RadioButton
        StaticButton                    matlab.ui.control.RadioButton
        CodeSmoothCB                    matlab.ui.control.CheckBox
        ClockJumpCB                     matlab.ui.control.CheckBox
        CycleSlipDetectionMethodsPanel  matlab.ui.container.Panel
        GFCycle                         matlab.ui.control.CheckBox
        MWCycle                         matlab.ui.control.CheckBox
        ElvAngle                        matlab.ui.control.NumericEditField
        ElevationCutOffAngleLabel       matlab.ui.control.Label
        Model                           matlab.ui.container.Tab
        OtherPanel                      matlab.ui.container.Panel
        SolidTide                       matlab.ui.control.CheckBox
        RelPath                         matlab.ui.control.CheckBox
        RelClock                        matlab.ui.control.CheckBox
        Atmosphere                      matlab.ui.container.Panel
        TroGrad                         matlab.ui.control.CheckBox
        Ionosphere                      matlab.ui.control.CheckBox
        Troposphere                     matlab.ui.control.CheckBox
        ReceiverPanel                   matlab.ui.container.Panel
        ARP                             matlab.ui.control.CheckBox
        APC                             matlab.ui.control.CheckBox
        SatellitePanel                  matlab.ui.container.Panel
        Windup                          matlab.ui.control.CheckBox
        MassCenter                      matlab.ui.control.CheckBox
        ClockOffset                     matlab.ui.control.CheckBox
        Filter                          matlab.ui.container.Tab
        mLabel_2                        matlab.ui.control.Label
        AprioriZ                        matlab.ui.control.NumericEditField
        ZLabel                          matlab.ui.control.Label
        AprioriY                        matlab.ui.control.NumericEditField
        YLabel                          matlab.ui.control.Label
        AprioriPosDD                    matlab.ui.control.DropDown
        APrioriPositionSourceDropDownLabel  matlab.ui.control.Label
        MeasurementOption               matlab.ui.container.Panel
        mLabel                          matlab.ui.control.Label
        PhaseMeas                       matlab.ui.control.NumericEditField
        PhaseMeasLabel                  matlab.ui.control.Label
        CodeMeas                        matlab.ui.control.NumericEditField
        CodeMeasLabel                   matlab.ui.control.Label
        MeasNoiseLabel                  matlab.ui.control.Label
        MeasWeight                      matlab.ui.control.DropDown
        WeightMethodLabel               matlab.ui.control.Label
        AprioriX                        matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        ProcessParametrer               matlab.ui.container.Panel
        PosNoise2                       matlab.ui.control.NumericEditField
        AmbNoise2_2Label_2              matlab.ui.control.Label
        STDNoise2                       matlab.ui.control.NumericEditField
        AmbNoise2_2Label                matlab.ui.control.Label
        STDNoise                        matlab.ui.control.NumericEditField
        SystemTimeDifferenceLabel_2     matlab.ui.control.Label
        STDNLabel                       matlab.ui.control.Label
        AmbNLabel                       matlab.ui.control.Label
        TropNLabel                      matlab.ui.control.Label
        RecClkNLabel                    matlab.ui.control.Label
        PosNLabel                       matlab.ui.control.Label
        AmbNoise2                       matlab.ui.control.NumericEditField
        PosNoise2_4Label                matlab.ui.control.Label
        TropNoise2                      matlab.ui.control.NumericEditField
        PosNoise2_3Label                matlab.ui.control.Label
        RecClkNoise2                    matlab.ui.control.NumericEditField
        PosNoise2_2Label                matlab.ui.control.Label
        AmbNoise                        matlab.ui.control.NumericEditField
        AmbParameter_2Label             matlab.ui.control.Label
        TropNoise                       matlab.ui.control.NumericEditField
        TropParameter_2Label            matlab.ui.control.Label
        RecClkNoise                     matlab.ui.control.NumericEditField
        RecClkParameter_2Label          matlab.ui.control.Label
        PosNoise                        matlab.ui.control.NumericEditField
        PosParameter_2Label             matlab.ui.control.Label
        InitialParameter                matlab.ui.container.Panel
        STDLabel                        matlab.ui.control.Label
        STDParameter2                   matlab.ui.control.NumericEditField
        AmbParameter2_2Label            matlab.ui.control.Label
        STDParameter                    matlab.ui.control.NumericEditField
        SystemTimeDifferenceLabel       matlab.ui.control.Label
        AmbParameter2                   matlab.ui.control.NumericEditField
        PosParameter2_4Label            matlab.ui.control.Label
        AmbLabel                        matlab.ui.control.Label
        TropParameter2                  matlab.ui.control.NumericEditField
        PosParameter2_3Label            matlab.ui.control.Label
        tropLabel                       matlab.ui.control.Label
        RecClkParameter2                matlab.ui.control.NumericEditField
        PosParameter2_2Label            matlab.ui.control.Label
        RecClkLabel2                    matlab.ui.control.Label
        PosParameter2                   matlab.ui.control.NumericEditField
        PosNoise2_2Label_2              matlab.ui.control.Label
        PosLabel                        matlab.ui.control.Label
        AmbParameter                    matlab.ui.control.NumericEditField
        PhaseAmbiguitiesLabel           matlab.ui.control.Label
        TropParameter                   matlab.ui.control.NumericEditField
        TrophosphereLabel               matlab.ui.control.Label
        RecClkParameter                 matlab.ui.control.NumericEditField
        ReceiverClockLabel              matlab.ui.control.Label
        PosParameter                    matlab.ui.control.NumericEditField
        PositionEditFieldLabel          matlab.ui.control.Label
        LSQR                            matlab.ui.control.CheckBox
        Analysis                        matlab.ui.container.Tab
        TZD                             matlab.ui.control.Button
        RecClkEst                       matlab.ui.control.Button
        ThrDPos                         matlab.ui.control.Button
        PDOP                            matlab.ui.control.Button
        SNO                             matlab.ui.control.Button
        HOR                             matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        CT                              matlab.ui.control.NumericEditField
        CTLabel                         matlab.ui.control.Label
        CTL                             matlab.ui.control.Label
        RU                              matlab.ui.control.NumericEditField
        UpLabel_2                       matlab.ui.control.Label
        RE                              matlab.ui.control.NumericEditField
        EastEditFieldLabel_2            matlab.ui.control.Label
        RN                              matlab.ui.control.NumericEditField
        NorthEditFieldLabel_2           matlab.ui.control.Label
        RMSL                            matlab.ui.control.Label
        AU                              matlab.ui.control.NumericEditField
        UpLabel                         matlab.ui.control.Label
        AE                              matlab.ui.control.NumericEditField
        EastEditFieldLabel              matlab.ui.control.Label
        AN                              matlab.ui.control.NumericEditField
        NorthEditFieldLabel             matlab.ui.control.Label
        AL                              matlab.ui.control.Label
        PTo                             matlab.ui.control.NumericEditField
        ToLabel                         matlab.ui.control.Label
        PFrom                           matlab.ui.control.NumericEditField
        FromEditField_2Label            matlab.ui.control.Label
        PI                              matlab.ui.control.Label
        NEU                             matlab.ui.control.Button
        RFL                             matlab.ui.control.Label
        refZ                            matlab.ui.control.NumericEditField
        ZLabel_2                        matlab.ui.control.Label
        refY                            matlab.ui.control.NumericEditField
        YLabel_2                        matlab.ui.control.Label
        refX                            matlab.ui.control.NumericEditField
        XLabel                          matlab.ui.control.Label
    end

    properties (Access = public)
        Data = struct(); % include data
        Options  = struct();
        Solution = struct();
        Import   = zeros(1);
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AntennaButton
        function AntennaButtonPushed(app, event)
           [file,path] = uigetfile('*.atx','Select the Antenna file');
           if ischar(file)
               anten = fullfile(path,file);
               app.Antenna.Editable    = 'on';
               app.Antenna.Value       = anten;
               app.Antenna.Editable    = 'off';
           else
               return
           end
        end

        % Value changed function: AprioriPosDD
        function AprioriPosDDValueChanged(app, event)
            value = app.AprioriPosDD.Value;
            if strcmp(value,'RINEX')
                app.AprioriX.Editable = 'off';
                app.AprioriY.Editable = 'off';
                app.AprioriZ.Editable = 'off';
            elseif strcmp(value,'Specify')
                app.AprioriX.Editable = 'on';
                app.AprioriY.Editable = 'on';
                app.AprioriZ.Editable = 'on';
            end 
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            xs  = app.Solution.xs;
            ref = [app.refX.Value;app.refY.Value;app.refZ.Value];
            [n,e,u,ct,~,rms] = evaluate(xs,ref);
            app.AN.Editable = 'on';
            app.AN.Value    = n(end,1)*100;
            app.AN.Editable = 'off';
            app.AE.Editable = 'on';
            app.AE.Value    = e(end,1)*100;
            app.AE.Editable = 'off';
            app.AU.Editable = 'on';
            app.AU.Value    = u(end,1)*100;
            app.AU.Editable = 'off';
            app.RN.Editable = 'on';
            app.RN.Value    = rms(1,1)*100;
            app.RN.Editable = 'off';
            app.RE.Editable = 'on';
            app.RE.Value    = rms(2,1)*100;
            app.RE.Editable = 'off';
            app.RU.Editable = 'on';
            app.RU.Value    = rms(3,1)*100;
            app.RU.Editable = 'off';
            app.CT.Editable = 'on';
            app.CT.Value    = ct;
            app.CT.Editable = 'off';
        end

        % Button pushed function: ClockButton
        function ClockButtonPushed(app, event)
           [file,path] = uigetfile('*.clk*','Select the Clock file');
           if ischar(file)
               clock = fullfile(path,file);
               app.Clock.Editable      = 'on';
               app.Clock.Value         = clock;
               app.Clock.Editable      = 'off';
           else
               return
           end
        end

        % Value changed function: ClockDropDown
        function ClockDropDownValueChanged(app, event)
            if strcmp(app.ClockDropDown.Value,'Clock File')
                app.Clock.Enable       = 'on';
                app.ClockButton.Enable = 'on';
                app.ClockFileIntervalEditField.Enable = 'on';
            elseif strcmp(app.ClockDropDown.Value,'Sp3 File')
                app.Clock.Editable     = 'on';
                app.Clock.Value        = '';
                app.ClockFileIntervalEditField.Enable = 'off';
                app.Clock.Editable     = 'off';
                app.Clock.Enable       = 'off';
                app.ClockButton.Enable = 'off';
            end
        end

        % Button pushed function: DCBButton
        function DCBButtonPushed(app, event)
            [file,path] = uigetfile('*.DCB','Select the DCB file.');
            if ischar(file)
                dcb = fullfile(path,file);
                app.DCB.Editable = 'on';
                app.DCB.Value    = dcb;
                app.DCB.Editable  = 'off';
            else
                return
            end
        end

        % Value changed function: DCBDropDown
        function DCBDropDownValueChanged(app, event)
            if strcmp(app.DCBDropDown.Value,'P1 - C1')
                app.DCB.Enable       = 'on';
                app.DCBButton.Enable = 'on';
            elseif strcmp(app.DCBDropDown.Value,'None')
                app.DCB.Editable     = 'on';
                app.DCB.Value        = '';
                app.DCB.Editable     = 'off';
                app.DCB.Enable       = 'off';
                app.DCBButton.Enable = 'off';
            end
        end

        % Button pushed function: DefaultConfigurationButton
        function DefaultConfigurationButtonPushed(app, event)
            % INPUT
            % Observation File
            app.Observation.Enable       = 'on';
            app.Observation.Editable     = 'on';
            app.Observation.Value        = '';
            app.Observation.Editable     = 'off';
            app.ObservationButton.Enable = 'on';
            % Orbit Files
            app.Orbit.Enable             = 'on';
            app.Orbit.Editable           = 'on';
            app.Orbit.Value              = '';
            app.Orbit.Editable           = 'off';
            app.OrbitButton.Enable       = 'on';
            app.Orbit_B.Enable           = 'on';
            app.Orbit_B.Editable         = 'on';
            app.Orbit_B.Value            = '';
            app.Orbit_B.Editable         = 'off';
            app.OrbitButton_B.Enable     = 'on';
            app.Orbit_A.Enable           = 'on';
            app.Orbit_A.Editable         = 'on';
            app.Orbit_A.Value            = '';
            app.Orbit_A.Editable         = 'off';
            app.OrbitButton_A.Enable     = 'on';
            % Antenna Files
            app.Antenna.Enable           = 'on';
            app.Antenna.Editable         = 'on';
            app.Antenna.Value            = '';
            app.Antenna.Editable         = 'off';
            app.AntennaButton.Enable     = 'on';
            % Clock File
            app.ClockDropDown.Enable     = 'on';
            app.ClockDropDown.Value      = 'Clock File';
            app.Clock.Enable             = 'on';
            app.Clock.Editable           = 'on';
            app.Clock.Value              = '';
            app.Clock.Editable           = 'off';
            app.ClockButton.Enable       = 'on';
            app.ClockFileIntervalEditField.Value = 30;
            % DCB File
            app.DCBDropDown.Enable       = 'on';
            app.DCBDropDown.Value        = 'None';
            app.DCB.Enable               = 'off';
            app.DCB.Editable             = 'off';
            app.DCB.Value                = '';
            app.DCB.Editable             = 'off';
            app.DCBButton.Enable         = 'off';
            % System Selection
            app.GPSCheckBox.Value        = 1;
            app.GLONASSCheckBox.Value    = 0;
            app.GALILEOCheckBox.Value    = 0;
            app.BEIDOUCheckBox.Value     = 0;
            
            app.ImportButton.Enable      = 'on';
            app.SaveAsButton.Enable      = 'on';
            app.SavedFileCB.Value        = 0;
            app.SavedFile.Enable         = 'off';
            app.SavedFile.Value          = '';
            app.SavedFileButton.Enable   = 'off';
            
            % PREPROCESS
            app.ElvAngle.Value           = 8;
            app.MWCycle.Value            = 1;
            app.GFCycle.Value            = 1;
            app.ClockJumpCB.Value        = 1;
            app.CodeSmoothCB.Value       = 0;
            app.StaticButton.Value       = 1;
            
            % MODEL
            app.ClockOffset.Value        =  1;
            app.MassCenter.Value         =  1;
            app.Windup.Value             =  1;
            app.APC.Value                =  1;
            app.ARP.Value                =  1;
            app.Troposphere.Value        =  1;
            app.TroGrad.Value           =  0;
            app.RelClock.Value           =  1;
            app.RelPath.Value            =  1;
            app.SolidTide.Value          =  1;
            
            % FILTER
            app.LSQR.Value               =  0;
            
            app.PosParameter.Editable    =  'on';
            app.PosParameter2.Editable   =  'on';
            app.RecClkParameter.Editable =  'on';
            app.RecClkParameter2.Editable=  'on';
            app.TropParameter.Editable   =  'on';
            app.TropParameter2.Editable  =  'on';
            app.AmbParameter.Editable    =  'on';
            app.AmbParameter2.Editable   =  'on';
            app.STDParameter.Editable    =  'on';
            app.STDParameter2.Editable   =  'on';
            
            app.PosParameter.Value       =  1;
            app.PosParameter2.Value      =  2;
            app.RecClkParameter.Value    =  1;
            app.RecClkParameter2.Value   =  5;
            app.TropParameter.Value      =  0.5;
            app.TropParameter2.Value     =  0;
            app.AmbParameter.Value       =  2;
            app.AmbParameter2.Value      =  1;
            app.STDParameter.Value       =  1;
            app.STDParameter2.Value      =  2;
            
            app.PosNoise.Value           = 0;
            app.PosNoise2.Value          = 0;
            app.PosNoise.Editable        = 'off';
            app.PosNoise2.Editable       = 'off';
            app.RecClkNoise.Value        =  1;
            app.RecClkNoise2.Value       =  5;
            app.TropNoise.Value          =  1;
            app.TropNoise2.Value         = -9;
            app.STDNoise.Value           =  1;
            app.STDNoise2.Value          = -7;
            
            app.AprioriPosDD.Enable      = 'on';
            app.AprioriPosDD.Value       = 'RINEX';
            app.AprioriX.Editable        =  'Off';
            app.AprioriY.Editable        =  'Off';
            app.AprioriZ.Editable        =  'Off';
            app.AprioriX.Value           =  0;
            app.AprioriY.Value           =  0;
            app.AprioriZ.Value           =  0;
            
            app.MeasWeight.Value         = 'Elevation Dependent';
            app.CodeMeas.Value           = 3;
            app.PhaseMeas.Value          = 0.003;
            
            % GENERAL
            app.FromEditField.Value      =  0;
            app.ToEditField.Value        = 86370;
        end

        % Button pushed function: HOR
        function HORButtonPushed(app, event)
            xs = app.Solution.xs;
            ref = [app.refX.Value;app.refY.Value;app.refZ.Value];
            [n,e,~,~,~,~] = evaluate(xs,ref);
            
            % boundaries
            % st = app.PFrom.Value/30 + 1;
            % fn = app.PTo.Value/30 + 1;
            % t = st:fn;
            % t = (t-1)./120; % hour
            
            fig = figure('Name','Horizontal Positioning Errors','NumberTitle','off','Color',[0.75 0.75 0.75]);
            ax  = axes(fig);
            plot(n(:,1),e(:,1),'*')
            ax.Title.String = 'Horizontal Positioning Errors';
            ax.XLabel.String = 'North Error (Meter)';
            ax.YLabel.String = 'East Error (Meter)';
            ax.XAxisLocation = 'origin';
            ax.YAxisLocation = 'origin';
            ax.XLim = [-1 1];
            ax.YLim = [-1 1];
            ax.XGrid = 'on';
            ax.YGrid = 'on';
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            try
                f = waitbar(0.25, {'Please wait...' 'Operation is processing'});
                if isempty(app.Observation.Value)
                    errordlg('Please select a valid observation file','File error');
                elseif isempty(app.Orbit.Value)
                    errordlg('Please select a valid orbit file','File error'); 
                elseif isempty(app.Antenna.Value)
                    errordlg('Please select a valid antenna file','File error');
                elseif strcmp(app.ClockDropDown.Value,'Clock File') && isempty(app.Clock.Value)
                    errordlg('Please select a valid clock file','File error');
                elseif strcmp(app.DCBDropDown.Value,'P1 - C1') && isempty(app.DCB.Value)
                    errordlg('Please select a valid DCB file','File error');
                elseif app.GPSCheckBox.Value == 0 && app.GLONASSCheckBox.Value == 0
                    errordlg('GPS or GLONASS have to be selected.','System selection error');
                else
                    % files
                    files.rinex  = app.Observation.Value;
                    files.orbitb = app.Orbit_B.Value;
                    files.orbit  = app.Orbit.Value;
                    files.orbita = app.Orbit_A.Value;
                    files.anten  = app.Antenna.Value;
                    files.clock  = app.Clock.Value;
                    files.dcb    = app.DCB.Value;
                    % options
                    options.clock      = app.ClockDropDown.Value;
                    options.system.gps = app.GPSCheckBox.Value;
                    options.system.glo = app.GLONASSCheckBox.Value;
                    options.system.gal = app.GALILEOCheckBox.Value;
                    options.system.bds = app.BEIDOUCheckBox.Value;
                    if ~isempty(app.DCB.Value)
                        options.dcb = 1;
                    else
                        options.dcb = 0;
                    end
                    options.clck_int   = app.ClockFileIntervalEditField.Value;
                    % read the files
                    [ppps] = data_hand(files,options);
                    
                    waitbar(0.75,f)
                    
                    app.Data = ppps;
                    % Specify the epoch time
                    app.FromEditField.Value = ppps.obs.ep(1,1);
                    app.ToEditField.Value = ppps.obs.ep(end,1);
                    % Specify the plot time
                    app.PFrom.Value = ppps.obs.ep(1,1);
                    app.PTo.Value = ppps.obs.ep(end,1);
                    
                    msgbox('Import operation successfully completed');
                    app.Import = 1;
                    
                    close(f)
                end
            catch ME
                if any(ME.identifier)
                    errordlg('Please check the files.','File Error');
                end
            end
        end

        % Value changed function: LSQR
        function LSQRValueChanged(app, event)
            value = app.LSQR.Value;
            if value == 1
                app.PosParameter.Editable     = 'off';
                app.PosParameter2.Editable    = 'off';
                app.RecClkParameter.Editable  = 'off';
                app.RecClkParameter2.Editable = 'off';
                app.TropParameter.Editable    = 'off';
                app.TropParameter2.Editable   = 'off';
                app.AmbParameter.Editable     = 'off';
                app.AmbParameter2.Editable    = 'off';
                app.STDParameter.Editable     = 'off';
                app.STDParameter2.Editable    = 'off';
                app.AprioriPosDD.Enable       = 'off';
                if strcmp(app.AprioriPosDD.Value,'Specify')
                    app.AprioriX.Value        =  0;
                    app.AprioriX.Editable     = 'off';
                    app.AprioriY.Value        =  0;
                    app.AprioriY.Editable     = 'off';
                    app.AprioriZ.Value        =  0;
                    app.AprioriZ.Editable     = 'off';
                end
            elseif value == 0
                app.PosParameter.Editable     = 'on';
                app.PosParameter2.Editable    = 'on';
                app.RecClkParameter.Editable  = 'on';
                app.RecClkParameter2.Editable = 'on';
                app.TropParameter.Editable    = 'on';
                app.TropParameter2.Editable   = 'on';
                app.AmbParameter.Editable     = 'on';
                app.AmbParameter2.Editable    = 'on';
                app.STDParameter.Editable     = 'on';
                app.STDParameter2.Editable    = 'on';
                app.AprioriPosDD.Enable       = 'on';
                if strcmp(app.AprioriPosDD.Value,'Specify')
                    app.AprioriX.Editable         = 'on';
                    app.AprioriY.Editable         = 'on';
                    app.AprioriZ.Editable         = 'on';
                end
            end
        end

        % Button pushed function: LoadSavedConfigurationButton
        function LoadSavedConfigurationButtonPushed(app, event)
            [file,path] = uigetfile('*.mat','Select the Previously Saved Data file');
            
            if ischar(file)
                Option = fullfile(path,file);
                load(Option)
                % INPUT
                app.GPSCheckBox.Value      = Option.GPSSelect;
                app.GLONASSCheckBox.Value  = Option.GLONASSSelect; 
                app.GALILEOCheckBox.Value  = Option.GALILEOSelect;
                app.BEIDOUCheckBox.Value   = Option.BEIDOUSelect;
                app.ClockDropDown.Value    = Option.ClockSelect;
                if strcmp(app.ClockDropDown.Value,'Clock File')
                    app.Clock.Enable = 'on';
                    app.ClockButton.Enable = 'on';
                else
                    app.Clock.Enable = 'off';
                    app.ClockButton.Enable = 'off';
                end
                app.ClockFileIntervalEditField.Value = Option.ClockInt;
                app.DCBDropDown.Value      = Option.DCBSelect;
                if strcmp(app.DCBDropDown.Value,'P1 - C1')
                    app.DCB.Enable = 'on';
                    app.DCBButton.Enable = 'on';
                else
                    app.DCB.Enable = 'off';
                    app.DCBButton.Enable = 'off';
                end
                % PREPROCESS
                app.ElvAngle.Value         = Option.ElvAngle;
                app.MWCycle.Value          = Option.CSMw;
                app.GFCycle.Value          = Option.CSGf;
                app.ClockJumpCB.Value      = Option.ClkJump;
                app.CodeSmoothCB.Value     = Option.CodSmth;
                app.StaticButton.Value     = Option.Static;
                app.KinematicButton.Value  = Option.Kinematic;
                
                % MODELLING
                app.ClockOffset.Value      = Option.SatClk;
                app.MassCenter.Value       = Option.SatAPC;
                app.Windup.Value           = Option.SatWind;
                app.APC.Value              = Option.RecAPC;
                app.ARP.Value              = Option.RecARP;
                app.Troposphere.Value      = Option.AtmTrop;
                if app.Troposphere.Value == 1
                    app.TroGrad.Enable = 'on';
                    app.TroGrad.Value = Option.TroGrad;
                elseif app.Troposphere.Value == 0
                    app.TroGrad.Enable = 'off';
                    app.TroGrad.Value = 0;
                end
                app.RelClock.Value         = Option.RelClk;
                app.RelPath.Value          = Option.RelPath;
                app.SolidTide.Value        = Option.Solid;
                
                % filter
                app.LSQR.Value             = Option.InMethod;
                
                if app.LSQR.Value == 0
                    app.PosParameter.Editable     = 'on';
                    app.PosParameter2.Editable    = 'on';
                    app.RecClkParameter.Editable  = 'on';
                    app.RecClkParameter2.Editable = 'on';
                    app.TropParameter.Editable    = 'on';
                    app.TropParameter2.Editable   = 'on';
                    app.AmbParameter.Editable     = 'on';
                    app.AmbParameter2.Editable    = 'on';
                    app.STDParameter.Editable     = 'on';
                    app.STDParameter2.Editable    = 'on';
                else
                    app.PosParameter.Editable     = 'off';
                    app.PosParameter2.Editable    = 'off';
                    app.RecClkParameter.Editable  = 'off';
                    app.RecClkParameter2.Editable = 'off';
                    app.TropParameter.Editable    = 'off';
                    app.TropParameter2.Editable   = 'off';
                    app.AmbParameter.Editable     = 'off';
                    app.AmbParameter2.Editable    = 'off';
                    app.STDParameter.Editable     = 'off';
                    app.STDParameter2.Editable    = 'off';
                end
                    
                app.PosParameter.Value     = Option.PosParameter;
                app.PosParameter2.Value    = Option.PosParameter2;
                app.RecClkParameter.Value  = Option.RecClkParameter;
                app.RecClkParameter2.Value = Option.RecClkParameter2;
                app.TropParameter.Value    = Option.TropParameter;
                app.TropParameter2.Value   = Option.TropParameter2;
                app.AmbParameter.Value     = Option.AmbParameter;
                app.AmbParameter2.Value    = Option.AmbParameter2;
                app.STDParameter.Value     = Option.STDParameter;
                app.STDParameter2.Value    = Option.STDParameter2;
                
                if app.StaticButton.Value == 1
                    app.PosNoise.Editable  = 'off';
                    app.PosNoise2.Editable = 'off';
                else
                    app.PosNoise.Editable  = 'on';
                    app.PosNoise2.Editable = 'on';
                end
                
                app.PosNoise.Value         = Option.NosPos;
                app.PosNoise2.Value        = Option.NosPos2;
                app.RecClkNoise.Value      = Option.NosClk;
                app.RecClkNoise2.Value     = Option.NosClk2;
                app.TropNoise.Value        = Option.NosTrop;
                app.TropNoise2.Value       = Option.NosTrop2;
                app.STDNoise.Value         = Option.NosSTD;
                app.STDNoise2.Value        = Option.NosSTD2;
                
                app.AprioriPosDD.Value     = Option.ApMethod;
                if strcmp(app.AprioriPosDD.Value,'RINEX')
                    app.AprioriX.Editable      =  'off';
                    app.AprioriY.Editable      =  'off';
                    app.AprioriZ.Editable      =  'off';
                else
                    app.AprioriX.Editable      =  'on';
                    app.AprioriY.Editable      =  'on';
                    app.AprioriZ.Editable      =  'on';
                end
                app.AprioriX.Value         = Option.AprioriX;
                app.AprioriY.Value         = Option.AprioriY;
                app.AprioriZ.Value         = Option.AprioriZ;
                
                app.MeasWeight.Value       = Option.WeMethod;
                app.CodeMeas.Value         = Option.CodeStd;
                app.PhaseMeas.Value        = Option.PhaseStd;
                % general
                app.FromEditField.Value    = Option.From ;
                app.ToEditField.Value      = Option.To ;
            end
        end

        % Callback function
        function MeasurementDropDownValueChanged(app, event)
            value = app.MeasurementDropDown.Value;
            if strcmp(value,'Static')
                app.PosNoise.Value     = 0;
                app.PosNoise2.Value    = 0;
                
                app.PosNoise.Editable  = 'off';
                app.PosNoise2.Editable = 'off';
            elseif strcmp(value,'Kinematic')
                app.PosNoise.Editable  = 'on';
                app.PosNoise2.Editable = 'on';
            end
        end

        % Button pushed function: NEU
        function NEUButtonPushed(app, event)
            xs = app.Solution.xs;
            ref = [app.refX.Value;app.refY.Value;app.refZ.Value];
            [n,e,u,~,~,~] = evaluate(xs,ref);
            
            % boundaries
            st = ((app.PFrom.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            fn = ((app.PTo.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            t = app.Data.obs.ep(st:fn,1);
            t = t./3600; % hour
            
            fig = figure('Name','NEU Positioning Errors','NumberTitle','off','Color',[0.75 0.75 0.75]);
            ax  = axes(fig);
            plot(ax,t,n(:,1),t,e(:,1),t,u(:,1))
            ax.Title.String = 'NEU Positioning Errors';
            ax.XLabel.String = 'Time (Hour)';
            ax.YLabel.String = 'Error (Meter)';
            min1 = t(1) - ((t(end)-t(1))*0.1);
            max1 = t(end);
            ax.XLim = [min1 max1];
            ax.XGrid = 'on';
            ax.YGrid = 'on';
            legend(ax,'North','East','Up')
        end

        % Button pushed function: ObservationButton
        function ObservationButtonPushed(app, event)
           [file,path] = uigetfile({'*.**o';'*.rnx'},'Select the Observation file');
           if ischar(file)
               rinex = fullfile(path,file);
               app.Observation.Editable = 'on';
               app.Observation.Value    = rinex;
               app.Observation.Editable = 'off';
           else
               return
           end
           
        end

        % Button pushed function: OrbitButton
        function OrbitButtonPushed(app, event)
           [file,path] = uigetfile('*.sp3','Select the Orbit file');
           if ischar(file)
               orbit = fullfile(path,file);
               app.Orbit.Editable       = 'on';
               app.Orbit.Value          = orbit;
               app.Orbit.Editable       = 'off';
           else
               return
           end
        end

        % Button pushed function: PDOP
        function PDOPButtonPushed(app, event)
            kof = app.Solution.kof;
            
            % boundaries
            st = ((app.PFrom.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            fn = ((app.PTo.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            t = app.Data.obs.ep(st:fn,1);
            t = t./3600; % hour
            gdop = zeros(size(t,1),1);
            pdop = zeros(size(t,1),1);
            tdop = zeros(size(t,1),1);
            
            n = 0;
            for i=1:size(kof,3)
                n = n+1;
                AK = kof(:,:,i);
                gdop(n,1) = sqrt(AK(1,1) + AK(2,2) + AK(3,3) + AK(4,4));
                pdop(n,1) = sqrt(AK(1,1) + AK(2,2) + AK(3,3));
                tdop(n,1) = sqrt(AK(1,1));
            end
            
            fig = figure('Name','Dilution of Precision','NumberTitle','off','Color',[0.75 0.75 0.75]);
            ax  = axes(fig);
            plot(t,gdop,t,pdop,t,tdop)
            ax.Title.String = 'Diluton of Precision';
            ax.XLabel.String = 'Time (Hour)';
            ax.XGrid = 'on';
            ax.YGrid = 'on';
            legend(ax,'GDOP','PDOP','TDOP')
        end

        % Button pushed function: RecClkEst
        function RecClkEstButtonPushed(app, event)
            xs = app.Solution.xs;
            
            % boundaries
            st = ((app.PFrom.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            fn = ((app.PTo.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            t = app.Data.obs.ep(st:fn,1);
            t = t./3600; % hour
            
            fig = figure('Name','Receiver Clock Estimation','NumberTitle','off','Color',[0.75 0.75 0.75]);
            ax  = axes(fig);
            plot(t,xs(4,:))
            ax.Title.String = 'Receiver Clock Estimation';
            ax.XLabel.String = 'Time (Hour)';
            ax.YLabel.String = 'Receiver Clock (Meter)';
            ax.XGrid = 'on';
            ax.YGrid = 'on';
            legend(ax,'Receiver Clock')
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            try
                f = waitbar(0.25, {'Please wait...' 'Operation is processing'});
                if app.Import ~= 1
                    errordlg('You have to import your files before push run button','Import Error');
                else
                    % from files
                    data = app.Data;
                    % from options
                    
                    % preprocess
                    Option.elvangle = app.ElvAngle.Value;
                    Option.CSMw     = app.MWCycle.Value;
                    Option.CSGf     = app.GFCycle.Value;
                    Option.clkjump  = app.ClockJumpCB.Value;
                    Option.codsmth  = app.CodeSmoothCB.Value;
                    if app.StaticButton.Value == 1
                        Option.ProMod = 1; %static
                    else
                        Option.ProMod = 0; %kinematic
                    end
                    
                    % model
                    Option.SatClk   = app.ClockOffset.Value;
                    Option.SatAPC   = app.MassCenter.Value;
                    Option.SatWind  = app.Windup.Value;
                    Option.RecAPC   = app.APC.Value;
                    Option.RecARP   = app.ARP.Value;
                    Option.AtmTrop  = app.Troposphere.Value;
                    Option.TroGrad  = app.TroGrad.Value;
                    Option.RelClk   = app.RelClock.Value;
                    Option.RelPath  = app.RelPath.Value;
                    Option.Solid    = app.SolidTide.Value;
                    
                    % filter
                    Option.InMethod = app.LSQR.Value;
                    if app.LSQR.Value == 0
                        Option.IntPos    = app.PosParameter.Value;
                        Option.IntPos2   = app.PosParameter2.Value;
                        Option.IntClk    = app.RecClkParameter.Value;
                        Option.IntClk2   = app.RecClkParameter2.Value;
                        Option.IntTrop   = app.TropParameter.Value;
                        Option.IntTrop2  = app.TropParameter2.Value;
                        Option.IntSTD    = app.STDParameter.Value;
                        Option.IntSTD2   = app.STDParameter2.Value;
                    end
                    Option.IntAmb    = app.AmbParameter.Value;
                    Option.IntAmb2   = app.AmbParameter2.Value;
                    
                    Option.NosPos   = app.PosNoise.Value;
                    Option.NosPos2  = app.PosNoise2.Value;
                    Option.NosClk   = app.RecClkNoise.Value;
                    Option.NosClk2  = app.RecClkNoise2.Value;
                    Option.NosTrop  = app.TropNoise.Value;
                    Option.NosTrop2 = app.TropNoise2.Value;
                    Option.NosSTD   = app.STDNoise.Value;
                    Option.NosSTD2  = app.STDNoise2.Value;
                    
                    Option.ApMethod = app.AprioriPosDD.Value;
                    if strcmp(app.AprioriPosDD.Value,'Specify')
                        Option.AprioriX = app.AprioriX.Value; 
                        Option.AprioriY = app.AprioriY.Value;
                        Option.AprioriZ = app.AprioriZ.Value;
                    end
                    
                    Option.WeMethod = app.MeasWeight.Value;
                    Option.CodeStd  = app.CodeMeas.Value;
                    Option.PhaseStd = app.PhaseMeas.Value;
                    
                    % general
                    Option.from     = app.FromEditField.Value;
                    Option.to       = app.ToEditField.Value;
                    
                    Option.system.gps = data.opt.system.gps;
                    Option.system.glo = data.opt.system.glo;
                    Option.system.gal = data.opt.system.gal;
                    Option.system.bds = data.opt.system.bds;
                    
                    % preprocess
                    [data] = preprocess(data,Option);
                    waitbar(0.4,f)
                    % modelling
                    [model] = nmodel(data,Option);
                    waitbar(0.65,f)
                    % filtering
                    [xs,kof,~] = MGNSS_filter(model,data,Option);
                    waitbar(0.9,f)
                    
                    app.Solution.xs = xs;
                    app.Solution.kof = kof;
                    app.Solution.model = model;
                    % filename = strcat(app.Observation.Value(1:4),app.Orbit.Value(4:8),'.txt');
                    [file1,path1] = uiputfile('*.txt','Save File As');
                    [file2,path2] = uiputfile('*.txt','Save File As');
                    filename1 = fullfile(path1,file1);
                    filename2 = fullfile(path2,file2);
                    % dlmwrite(filename,xs','delimiter','\t','precision','%.3f')
                    out_write(xs,Option,model,filename1,filename2,data);
                    % outelev(xs,model,filename,data);
                    msgbox('Operation Successfully Completed');
                    app.NEU.Enable  = 'on';
                    app.CalculateButton.Enable  = 'on';
                    app.HOR.Enable  = 'on';
                    app.SNO.Enable  = 'on';
                    app.PDOP.Enable  = 'on';
                    app.ThrDPos.Enable = 'on';
                    app.RecClkEst.Enable = 'on';
                    app.TZD.Enable = 'on';
                    
                    close(f)
                end
            catch ME
                if any(ME.identifier)
                    errordlg('Please check your files','Run Error');
                end 
            end
        end

        % Button pushed function: SNO
        function SNOButtonPushed(app, event)
            data = app.Data;
            [satno] = dtr_satno(data.obs);
            
            % boundaries
            st = ((app.PFrom.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            fn = ((app.PTo.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            t = app.Data.obs.ep(st:fn,1);
            t = t./3600; % hour
            
            fig = figure('Name','Visible Satellite Numbers','NumberTitle','off','Color',[0.75 0.75 0.75]);
            ax  = axes(fig);
            plot(t,satno(st:fn,1))
            ax.Title.String = 'Visible Satellite Numbers';
            ax.XLabel.String = 'Time (Hour)';
            ax.YLabel.String = 'Satellite Number';
            ax.YLim = [0 max(satno(st:fn,1))+5];
            ax.YTick = 0:2:max(satno)+5;
            ax.XGrid = 'on';
            ax.YGrid = 'on';
            legend(ax,'Satellite Number')
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            try
                if app.Import == 1
                    data = app.Data;
                    uisave({'data'});
                else
                    errordlg('You have to import your files before push save button','Import Error');
                end
            catch ME
                if any(ME.identifier)
                    errordlg('Please import your files','Import Error');
                end
            end
        end

        % Button pushed function: SaveConfigurationButton
        function SaveConfigurationButtonPushed(app, event)
            % INPUT
            Option.GPSSelect        = app.GPSCheckBox.Value;
            Option.GLONASSSelect    = app.GLONASSCheckBox.Value;
            Option.GALILEOSelect    = app.GALILEOCheckBox.Value;
            Option.BEIDOUSelect     = app.BEIDOUCheckBox.Value;
            Option.ClockSelect      = app.ClockDropDown.Value;
            Option.ClockInt         = app.ClockFileIntervalEditField.Value;
            Option.DCBSelect        = app.DCBDropDown.Value;
            % PREPROCESS
            Option.ElvAngle         = app.ElvAngle.Value;
            Option.CSMw             = app.MWCycle.Value;
            Option.CSGf             = app.GFCycle.Value;
            Option.ClkJump          = app.ClockJumpCB.Value;
            Option.CodSmth          = app.CodeSmoothCB.Value;
            Option.Static           = app.StaticButton.Value;
            Option.Kinematic        = app.KinematicButton.Value;
            % MODELLING
            Option.SatClk           = app.ClockOffset.Value;
            Option.SatAPC           = app.MassCenter.Value;
            Option.SatWind          = app.Windup.Value;
            Option.RecAPC           = app.APC.Value;
            Option.RecARP           = app.ARP.Value;
            Option.AtmTrop          = app.Troposphere.Value;
            Option.TroGrad         = app.TroGrad.Value;
            Option.RelClk           = app.RelClock.Value;
            Option.RelPath          = app.RelPath.Value;
            Option.Solid            = app.SolidTide.Value;
            % filter
            Option.InMethod         = app.LSQR.Value;
            
            Option.PosParameter     = app.PosParameter.Value;
            Option.PosParameter2    = app.PosParameter2.Value;
            Option.RecClkParameter  = app.RecClkParameter.Value;
            Option.RecClkParameter2 = app.RecClkParameter2.Value;
            Option.TropParameter    = app.TropParameter.Value;
            Option.TropParameter2   = app.TropParameter2.Value;
            Option.AmbParameter     = app.AmbParameter.Value;
            Option.AmbParameter2    = app.AmbParameter2.Value;
            Option.STDParameter     = app.STDParameter.Value;
            Option.STDParameter2    = app.STDParameter2.Value;
            
            Option.NosPos           = app.PosNoise.Value;
            Option.NosPos2          = app.PosNoise2.Value;
            Option.NosClk           = app.RecClkNoise.Value;
            Option.NosClk2          = app.RecClkNoise2.Value;
            Option.NosTrop          = app.TropNoise.Value;
            Option.NosTrop2         = app.TropNoise2.Value;
            Option.NosSTD           = app.STDNoise.Value;
            Option.NosSTD2          = app.STDNoise2.Value;
            
            Option.ApMethod         = app.AprioriPosDD.Value;
            Option.AprioriX         = app.AprioriX.Value;
            Option.AprioriY         = app.AprioriY.Value;
            Option.AprioriZ         = app.AprioriZ.Value;
            
            Option.WeMethod         = app.MeasWeight.Value;
            Option.CodeStd          = app.CodeMeas.Value;
            Option.PhaseStd         = app.PhaseMeas.Value;
            % GENERAL
            Option.From             = app.FromEditField.Value;
            Option.To               = app.ToEditField.Value;
            
            uisave({'Option'});
        end

        % Button pushed function: SavedFileButton
        function SavedFileButtonPushed(app, event)
           [file,path] = uigetfile('*.mat','Select the Previously Saved Data file');
           if ischar(file)
               ppps = fullfile(path,file);
               app.SavedFile.Editable = 'on';
               app.SavedFile.Value    = ppps;
               app.SavedFile.Editable = 'off';
               load(ppps);
               app.Data = data(:);
               app.Import = 1;
           else
               return
           end
        end

        % Value changed function: SavedFileCB
        function SavedFileCBValueChanged(app, event)
            value = app.SavedFileCB.Value;
            if value == 1
                app.Observation.Enable       = 'off';
                app.ObservationButton.Enable = 'off';
                app.Orbit.Enable             = 'off';
                app.OrbitButton.Enable       = 'off';
                app.Orbit_B.Enable           = 'off';
                app.OrbitButton_B.Enable     = 'off';
                app.Orbit_A.Enable           = 'off';
                app.OrbitButton_A.Enable     = 'off';
                app.ClockDropDown.Enable     = 'off';
                app.Clock.Enable             = 'off';
                app.ClockButton.Enable       = 'off';
                app.Antenna.Enable           = 'off';
                app.AntennaButton.Enable     = 'off';
                app.ImportButton.Enable      = 'off';
                app.SaveAsButton.Enable      = 'off';
                app.DCBDropDown.Enable       = 'off';
                app.DCBButton.Enable         = 'off';
                app.DCB.Enable               = 'off';
                app.ClockFileIntervalEditField.Enable = 'off';
                app.GPSCheckBox.Enable       = 'off';
                app.GLONASSCheckBox.Enable   = 'off';
                app.GALILEOCheckBox.Enable   = 'off';
                app.BEIDOUCheckBox.Enable    = 'off';
                
                app.SavedFile.Enable         = 'on';
                app.SavedFileButton.Enable   = 'on';
            elseif value == 0
                app.Observation.Enable       = 'on';
                app.ObservationButton.Enable = 'on';
                app.Orbit.Enable             = 'on';
                app.OrbitButton.Enable       = 'on';
                app.Orbit_B.Enable           = 'on';
                app.OrbitButton_B.Enable     = 'on';
                app.Orbit_A.Enable           = 'on';
                app.OrbitButton_A.Enable     = 'on';
                app.ClockDropDown.Enable     = 'on';
                if strcmp(app.ClockDropDown.Value,'Clock File')
                    app.ClockButton.Enable   = 'on';
                    app.Clock.Enable         = 'on';
                end
                app.Antenna.Enable           = 'on';
                app.AntennaButton.Enable     = 'on';
                app.ImportButton.Enable      = 'on';
                app.SaveAsButton.Enable      = 'on';
                app.DCBDropDown.Enable       = 'on';
                if strcmp(app.DCBDropDown.Value,'P1 - C1')
                    app.DCBButton.Enable     = 'on';
                    app.DCB.Enable           = 'on';
                end
                app.ClockFileIntervalEditField.Enable = 'on';
                app.GPSCheckBox.Enable       = 'on';
                app.GLONASSCheckBox.Enable   = 'on';
                app.GALILEOCheckBox.Enable   = 'on';
                app.BEIDOUCheckBox.Enable    = 'on';
                
                app.SavedFile.Enable         = 'off';
                app.SavedFileButton.Enable   = 'off';
            end
        end

        % Button pushed function: ThrDPos
        function ThrDPosButtonPushed(app, event)
            xs = app.Solution.xs;
            ref = [app.refX.Value;app.refY.Value;app.refZ.Value];
            [~,~,~,~,ThrD,~] = evaluate(xs,ref);
            
            % boundaries
            st = ((app.PFrom.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            fn = ((app.PTo.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            t = app.Data.obs.ep(st:fn,1);
            t = t./3600; % hour
            
            fig = figure('Name','3D Positioning Errors','NumberTitle','off','Color',[0.75 0.75 0.75]);
            ax  = axes(fig);
            plot(t,ThrD(:,1))
            ax.Title.String = '3D Positioning Errors';
            ax.XLabel.String = 'Time (Hour)';
            ax.YLabel.String = 'Error (Meter)';
            td = (t(end) - t(1))*0.1;
            min1 = t(1)-td;
            max1 = t(end)+td;
            ax.XLim = [min1 max1];
            ax.XGrid = 'on';
            ax.YGrid = 'on';
            legend(ax,'3D Positioning Error')
        end

        % Button pushed function: OrbitButton_B
        function OrbitButton_BPushed(app, event)
           [file,path] = uigetfile('*.sp3','Select the Orbit file');
           if ischar(file)
               orbitb = fullfile(path,file);
               app.Orbit_B.Editable       = 'on';
               app.Orbit_B.Value          = orbitb;
               app.Orbit_B.Editable       = 'off';
           else
               return
           end
        end

        % Button pushed function: OrbitButton_A
        function OrbitButton_APushed(app, event)
           [file,path] = uigetfile('*.sp3','Select the Orbit file');
           if ischar(file)
               orbita = fullfile(path,file);
               app.Orbit_A.Editable       = 'on';
               app.Orbit_A.Value          = orbita;
               app.Orbit_A.Editable       = 'off';
           else
               return
           end
        end

        % Selection changed function: ProcessingModeButtonGroup
        function ProcessingModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProcessingModeButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Kinematic')
                app.PosNoise.Editable = 'on';
                app.PosNoise2.Editable = 'on';
            elseif strcmp(selectedButton.Text,'Static')
                app.PosNoise.Value = 0;
                app.PosNoise.Editable = 'off';
                app.PosNoise2.Value = 0;
                app.PosNoise2.Editable = 'off';
            end
        end

        % Value changed function: Troposphere
        function TroposphereValueChanged(app, event)
            value = app.Troposphere.Value;
            if value == 1
                app.TroGrad.Enable = 'on';
            else
                app.TroGrad.Value  = 0;
                app.TroGrad.Enable = 'off';
            end
        end

        % Button pushed function: TZD
        function TZDButtonPushed(app, event)
            xs = app.Solution.xs;
            model = app.Solution.model;
            tzd = model(1,31) + xs(5,:)';
            
            % boundaries
            st = ((app.PFrom.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            fn = ((app.PTo.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            t = app.Data.obs.ep(st:fn,1);
            t = t./3600; % hour
            
            fig = figure('Name','Tropospheric Zenith Total Delay','NumberTitle','off','Color',[0.75 0.75 0.75]);
            ax  = axes(fig);
            plot(t,tzd)
            ax.Title.String = 'Tropospheric Zenith Total Delay';
            ax.XLabel.String = 'Time (Hour)';
            ax.YLabel.String = 'Zenith Total Delay (Meter)';
            ax.XGrid = 'on';
            ax.YGrid = 'on';
            legend(ax,'Zenith Total Delay')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Main = uifigure('Visible', 'off');
            app.Main.Name = 'PPPH';
            app.Tabs = uitabgroup(app.Main);
            app.Input = uitab(app.Tabs);
            app.Input.Title = 'DATA IMPORTING';
            app.ObservationFileLabel = uilabel(app.Input);
            app.ObservationFileLabel.Text = 'Observation File';
            app.Observation = uieditfield(app.Input, 'text');
            app.Observation.Editable = 'off';
            app.ClockSourceLabel = uilabel(app.Input);
            app.ClockSourceLabel.Text = 'Clock Source';
            app.ClockDropDown = uidropdown(app.Input);
            app.ClockDropDown.Items = {'Sp3 File', 'Clock File'};
            app.ClockDropDown.ValueChangedFcn = createCallbackFcn(app, @ClockDropDownValueChanged, true);
            app.ClockDropDown.Value = 'Clock File';
            app.ClockFileLabel = uilabel(app.Input);
            app.ClockFileLabel.Text = 'Clock File';
            app.Clock = uieditfield(app.Input, 'text');
            app.Clock.Editable = 'off';
            app.AntennaFileEditFieldLabel = uilabel(app.Input);
            app.AntennaFileEditFieldLabel.Text = 'Antenna File';
            app.Antenna = uieditfield(app.Input, 'text');
            app.Antenna.Editable = 'off';
            app.ObservationButton = uibutton(app.Input, 'push');
            app.ObservationButton.ButtonPushedFcn = createCallbackFcn(app, @ObservationButtonPushed, true);
            app.ObservationButton.Icon = 'icon-folder-128.png';
            app.ObservationButton.Text = '';
            app.ClockButton = uibutton(app.Input, 'push');
            app.ClockButton.ButtonPushedFcn = createCallbackFcn(app, @ClockButtonPushed, true);
            app.ClockButton.Icon = 'icon-folder-128.png';
            app.ClockButton.Text = '';
            app.AntennaButton = uibutton(app.Input, 'push');
            app.AntennaButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaButtonPushed, true);
            app.AntennaButton.Icon = 'icon-folder-128.png';
            app.AntennaButton.Text = '';
            app.SaveAsButton = uibutton(app.Input, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Text = 'Save As';
            app.ImportButton = uibutton(app.Input, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Icon = 'Import_B-128.png';
            app.ImportButton.Text = 'Import';
            app.SavedFileCB = uicheckbox(app.Input);
            app.SavedFileCB.ValueChangedFcn = createCallbackFcn(app, @SavedFileCBValueChanged, true);
            app.SavedFileCB.Text = 'Import previously saved file';
            app.ChoosesavedfileLabel = uilabel(app.Input);
            app.ChoosesavedfileLabel.Enable = 'off';
            app.ChoosesavedfileLabel.Text = 'Choose saved file';
            app.SavedFile = uieditfield(app.Input, 'text');
            app.SavedFile.Editable = 'off';
            app.SavedFile.Enable = 'off';
            app.SavedFileButton = uibutton(app.Input, 'push');
            app.SavedFileButton.ButtonPushedFcn = createCallbackFcn(app, @SavedFileButtonPushed, true);
            app.SavedFileButton.Icon = 'icon-folder-128.png';
            app.SavedFileButton.Enable = 'off';
            app.SavedFileButton.Text = '';
            app.DCBButton = uibutton(app.Input, 'push');
            app.DCBButton.ButtonPushedFcn = createCallbackFcn(app, @DCBButtonPushed, true);
            app.DCBButton.Icon = 'icon-folder-128.png';
            app.DCBButton.Enable = 'off';
            app.DCBButton.Text = '';
            app.DCBFileLabel = uilabel(app.Input);
            app.DCBFileLabel.Text = 'DCB File';
            app.DCB = uieditfield(app.Input, 'text');
            app.DCB.Editable = 'off';
            app.DCB.Enable = 'off';
            app.DCBLabel = uilabel(app.Input);
            app.DCBLabel.Text = 'DCB';
            app.DCBDropDown = uidropdown(app.Input);
            app.DCBDropDown.Items = {'P1 - C1', 'None'};
            app.DCBDropDown.ValueChangedFcn = createCallbackFcn(app, @DCBDropDownValueChanged, true);
            app.DCBDropDown.Value = 'None';
            app.SystemSelectionLabel = uilabel(app.Input);
            app.SystemSelectionLabel.Text = 'System Selection';
            app.GPSCheckBox = uicheckbox(app.Input);
            app.GPSCheckBox.Text = 'GPS';
            app.GPSCheckBox.Value = true;
            app.GLONASSCheckBox = uicheckbox(app.Input);
            app.GLONASSCheckBox.Text = 'GLONASS';
            app.GALILEOCheckBox = uicheckbox(app.Input);
            app.GALILEOCheckBox.Text = 'GALILEO';
            app.BEIDOUCheckBox = uicheckbox(app.Input);
            app.BEIDOUCheckBox.Text = 'BEIDOU';
            app.ClockFileIntervalEditFieldLabel = uilabel(app.Input);
            app.ClockFileIntervalEditFieldLabel.Text = 'Clock File Interval';
            app.ClockFileIntervalEditField = uieditfield(app.Input, 'numeric');
            app.ClockFileIntervalEditField.ValueDisplayFormat = '%.0f';
            app.ClockFileIntervalEditField.Value = 30;
            app.OrbitFilesForPanel = uipanel(app.Input);
            app.OrbitFilesForPanel.Title = 'Orbit Files For';
            app.OrbitButton = uibutton(app.OrbitFilesForPanel, 'push');
            app.OrbitButton.ButtonPushedFcn = createCallbackFcn(app, @OrbitButtonPushed, true);
            app.OrbitButton.Icon = 'icon-folder-128.png';
            app.OrbitButton.Text = '';
            app.ObservationdayLabel = uilabel(app.OrbitFilesForPanel);
            app.ObservationdayLabel.Text = 'Observation day';
            app.Orbit = uieditfield(app.OrbitFilesForPanel, 'text');
            app.Orbit.Editable = 'off';
            app.OrbitButton_B = uibutton(app.OrbitFilesForPanel, 'push');
            app.OrbitButton_B.ButtonPushedFcn = createCallbackFcn(app, @OrbitButton_BPushed, true);
            app.OrbitButton_B.Icon = 'icon-folder-128.png';
            app.OrbitButton_B.Text = '';
            app.PreviousdayLabel = uilabel(app.OrbitFilesForPanel);
            app.PreviousdayLabel.Text = 'Previous day';
            app.Orbit_B = uieditfield(app.OrbitFilesForPanel, 'text');
            app.Orbit_B.Editable = 'off';
            app.OrbitButton_A = uibutton(app.OrbitFilesForPanel, 'push');
            app.OrbitButton_A.ButtonPushedFcn = createCallbackFcn(app, @OrbitButton_APushed, true);
            app.OrbitButton_A.Icon = 'icon-folder-128.png';
            app.OrbitButton_A.Text = '';
            app.NextdayLabel = uilabel(app.OrbitFilesForPanel);
            app.NextdayLabel.Text = 'Next day';
            app.Orbit_A = uieditfield(app.OrbitFilesForPanel, 'text');
            app.Orbit_A.Editable = 'off';
            app.secLabel = uilabel(app.Input);
            app.secLabel.Text = 'sec';
            app.Preprocess = uitab(app.Tabs);
            app.Preprocess.Title = 'PREPROCESSING';
            app.ElevationCutOffAngleLabel = uilabel(app.Preprocess);
            app.ElevationCutOffAngleLabel.Text = 'Elevation Cut-Off Angle';
            app.ElvAngle = uieditfield(app.Preprocess, 'numeric');
            app.ElvAngle.Limits = [0 90];
            app.ElvAngle.ValueDisplayFormat = '%.0f';
            app.ElvAngle.Value = 8;
            app.CycleSlipDetectionMethodsPanel = uipanel(app.Preprocess);
            app.CycleSlipDetectionMethodsPanel.Title = 'Cycle Slip Detection Methods';
            app.MWCycle = uicheckbox(app.CycleSlipDetectionMethodsPanel);
            app.MWCycle.Text = 'Melbourne-Wubbena Combination';
            app.MWCycle.Value = true;
            app.GFCycle = uicheckbox(app.CycleSlipDetectionMethodsPanel);
            app.GFCycle.Text = 'Geometry-Free Combination';
            app.GFCycle.Value = true;
            app.ClockJumpCB = uicheckbox(app.Preprocess);
            app.ClockJumpCB.Text = 'Clock Jump Detection and Repairment';
            app.ClockJumpCB.Value = true;
            app.CodeSmoothCB = uicheckbox(app.Preprocess);
            app.CodeSmoothCB.Text = 'Code Smoothing Using Phase Observations';
            app.ProcessingModeButtonGroup = uibuttongroup(app.Preprocess);
            app.ProcessingModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProcessingModeButtonGroupSelectionChanged, true);
            app.ProcessingModeButtonGroup.Title = 'Processing Mode';
            app.StaticButton = uiradiobutton(app.ProcessingModeButtonGroup);
            app.StaticButton.Text = 'Static';
            app.StaticButton.Value = true;
            app.KinematicButton = uiradiobutton(app.ProcessingModeButtonGroup);
            app.KinematicButton.Text = 'Kinematic';
            app.degreeLabel = uilabel(app.Preprocess);
            app.degreeLabel.Text = 'degree';
            app.Model = uitab(app.Tabs);
            app.Model.Title = 'MODELING OPTIONS';
            app.SatellitePanel = uipanel(app.Model);
            app.SatellitePanel.Title = 'Satellite';
            app.ClockOffset = uicheckbox(app.SatellitePanel);
            app.ClockOffset.Text = 'Clock Offset Correction';
            app.ClockOffset.Value = true;
            app.MassCenter = uicheckbox(app.SatellitePanel);
            app.MassCenter.Text = 'Mass Center to Antenna Phase Center Correction';
            app.MassCenter.Value = true;
            app.Windup = uicheckbox(app.SatellitePanel);
            app.Windup.Text = 'Windup Correction';
            app.Windup.Value = true;
            app.ReceiverPanel = uipanel(app.Model);
            app.ReceiverPanel.Title = 'Receiver';
            app.APC = uicheckbox(app.ReceiverPanel);
            app.APC.Text = 'Antenna Phase Center Correction';
            app.APC.Value = true;
            app.ARP = uicheckbox(app.ReceiverPanel);
            app.ARP.Text = 'Antenna Reference Point Correction';
            app.ARP.Value = true;
            app.Atmosphere = uipanel(app.Model);
            app.Atmosphere.Title = 'Atmosphere';
            app.Troposphere = uicheckbox(app.Atmosphere);
            app.Troposphere.ValueChangedFcn = createCallbackFcn(app, @TroposphereValueChanged, true);
            app.Troposphere.Text = 'Trophospheric Correction';
            app.Troposphere.Value = true;
            app.Ionosphere = uicheckbox(app.Atmosphere);
            app.Ionosphere.Enable = 'off';
            app.Ionosphere.Text = 'Ionospheric Correctiıon';
            app.TroGrad = uicheckbox(app.Atmosphere);
            app.TroGrad.Text = 'Estimate Tropospheric Gradients';
            app.OtherPanel = uipanel(app.Model);
            app.OtherPanel.Title = 'Other';
            app.RelClock = uicheckbox(app.OtherPanel);
            app.RelClock.Text = 'Relativistic Clock Correction';
            app.RelClock.Value = true;
            app.RelPath = uicheckbox(app.OtherPanel);
            app.RelPath.Text = 'Relativistic Path Range Correction';
            app.RelPath.Value = true;
            app.SolidTide = uicheckbox(app.OtherPanel);
            app.SolidTide.Text = 'Solid Tide Correction';
            app.SolidTide.Value = true;
            app.Filter = uitab(app.Tabs);
            app.Filter.Title = 'FILTERING OPTIONS';
            app.LSQR = uicheckbox(app.Filter);
            app.LSQR.ValueChangedFcn = createCallbackFcn(app, @LSQRValueChanged, true);
            app.LSQR.Text = 'Determine Initial Parameters Using Least Squares';
            app.InitialParameter = uipanel(app.Filter);
            app.InitialParameter.Title = 'Select Initial Uncertainty Parameters';
            app.PositionEditFieldLabel = uilabel(app.InitialParameter);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PosParameter = uieditfield(app.InitialParameter, 'numeric');
            app.PosParameter.ValueDisplayFormat = '%.1f';
            app.PosParameter.Value = 1;
            app.ReceiverClockLabel = uilabel(app.InitialParameter);
            app.ReceiverClockLabel.Text = 'Receiver Clock';
            app.RecClkParameter = uieditfield(app.InitialParameter, 'numeric');
            app.RecClkParameter.ValueDisplayFormat = '%.1f';
            app.RecClkParameter.Value = 1;
            app.TrophosphereLabel = uilabel(app.InitialParameter);
            app.TrophosphereLabel.Text = 'Trophosphere';
            app.TropParameter = uieditfield(app.InitialParameter, 'numeric');
            app.TropParameter.ValueDisplayFormat = '%.1f';
            app.TropParameter.Value = 0.5;
            app.PhaseAmbiguitiesLabel = uilabel(app.InitialParameter);
            app.PhaseAmbiguitiesLabel.Text = 'Phase Ambiguities';
            app.AmbParameter = uieditfield(app.InitialParameter, 'numeric');
            app.AmbParameter.ValueDisplayFormat = '%.1f';
            app.AmbParameter.Value = 2;
            app.PosLabel = uilabel(app.InitialParameter);
            app.PosLabel.Text = 'm';
            app.PosNoise2_2Label_2 = uilabel(app.InitialParameter);
            app.PosNoise2_2Label_2.Text = 'e';
            app.PosParameter2 = uieditfield(app.InitialParameter, 'numeric');
            app.PosParameter2.ValueDisplayFormat = '%.0f';
            app.PosParameter2.Value = 2;
            app.RecClkLabel2 = uilabel(app.InitialParameter);
            app.RecClkLabel2.Text = 'm';
            app.PosParameter2_2Label = uilabel(app.InitialParameter);
            app.PosParameter2_2Label.Text = 'e';
            app.RecClkParameter2 = uieditfield(app.InitialParameter, 'numeric');
            app.RecClkParameter2.ValueDisplayFormat = '%.0f';
            app.RecClkParameter2.Value = 5;
            app.tropLabel = uilabel(app.InitialParameter);
            app.tropLabel.Text = 'm';
            app.PosParameter2_3Label = uilabel(app.InitialParameter);
            app.PosParameter2_3Label.Text = 'e';
            app.TropParameter2 = uieditfield(app.InitialParameter, 'numeric');
            app.TropParameter2.ValueDisplayFormat = '%.0f';
            app.AmbLabel = uilabel(app.InitialParameter);
            app.AmbLabel.Text = 'm';
            app.PosParameter2_4Label = uilabel(app.InitialParameter);
            app.PosParameter2_4Label.Text = 'e';
            app.AmbParameter2 = uieditfield(app.InitialParameter, 'numeric');
            app.AmbParameter2.ValueDisplayFormat = '%.0f';
            app.AmbParameter2.Value = 1;
            app.SystemTimeDifferenceLabel = uilabel(app.InitialParameter);
            app.SystemTimeDifferenceLabel.Text = 'System Time Difference';
            app.STDParameter = uieditfield(app.InitialParameter, 'numeric');
            app.STDParameter.ValueDisplayFormat = '%.1f';
            app.STDParameter.Value = 1;
            app.AmbParameter2_2Label = uilabel(app.InitialParameter);
            app.AmbParameter2_2Label.Text = 'e';
            app.STDParameter2 = uieditfield(app.InitialParameter, 'numeric');
            app.STDParameter2.ValueDisplayFormat = '%.0f';
            app.STDParameter2.Value = 2;
            app.STDLabel = uilabel(app.InitialParameter);
            app.STDLabel.Text = 'm';
            app.ProcessParametrer = uipanel(app.Filter);
            app.ProcessParametrer.Title = 'Select Process Uncertainty Parameters';
            app.PosParameter_2Label = uilabel(app.ProcessParametrer);
            app.PosParameter_2Label.Text = 'Position';
            app.PosNoise = uieditfield(app.ProcessParametrer, 'numeric');
            app.PosNoise.ValueDisplayFormat = '%.1f';
            app.PosNoise.Editable = 'off';
            app.RecClkParameter_2Label = uilabel(app.ProcessParametrer);
            app.RecClkParameter_2Label.Text = 'Receiver Clock';
            app.RecClkNoise = uieditfield(app.ProcessParametrer, 'numeric');
            app.RecClkNoise.ValueDisplayFormat = '%.13f';
            app.RecClkNoise.Value = 1;
            app.TropParameter_2Label = uilabel(app.ProcessParametrer);
            app.TropParameter_2Label.Text = 'Trophosphere';
            app.TropNoise = uieditfield(app.ProcessParametrer, 'numeric');
            app.TropNoise.ValueDisplayFormat = '%.1f';
            app.TropNoise.Value = 1;
            app.AmbParameter_2Label = uilabel(app.ProcessParametrer);
            app.AmbParameter_2Label.Text = 'Phase Ambiguities';
            app.AmbNoise = uieditfield(app.ProcessParametrer, 'numeric');
            app.AmbNoise.ValueDisplayFormat = '%.1f';
            app.AmbNoise.Editable = 'off';
            app.PosNoise2_2Label = uilabel(app.ProcessParametrer);
            app.PosNoise2_2Label.Text = 'e';
            app.RecClkNoise2 = uieditfield(app.ProcessParametrer, 'numeric');
            app.RecClkNoise2.Value = 5;
            app.PosNoise2_3Label = uilabel(app.ProcessParametrer);
            app.PosNoise2_3Label.Text = 'e';
            app.TropNoise2 = uieditfield(app.ProcessParametrer, 'numeric');
            app.TropNoise2.Value = -9;
            app.PosNoise2_4Label = uilabel(app.ProcessParametrer);
            app.PosNoise2_4Label.Text = 'e';
            app.AmbNoise2 = uieditfield(app.ProcessParametrer, 'numeric');
            app.AmbNoise2.Editable = 'off';
            app.PosNLabel = uilabel(app.ProcessParametrer);
            app.PosNLabel.Text = 'm2/sec';
            app.RecClkNLabel = uilabel(app.ProcessParametrer);
            app.RecClkNLabel.Text = 'm2/sec';
            app.TropNLabel = uilabel(app.ProcessParametrer);
            app.TropNLabel.Text = 'm2/sec';
            app.AmbNLabel = uilabel(app.ProcessParametrer);
            app.AmbNLabel.Text = 'm2/sec';
            app.STDNLabel = uilabel(app.ProcessParametrer);
            app.STDNLabel.Text = 'm2/sec';
            app.SystemTimeDifferenceLabel_2 = uilabel(app.ProcessParametrer);
            app.SystemTimeDifferenceLabel_2.Text = 'System Time Difference';
            app.STDNoise = uieditfield(app.ProcessParametrer, 'numeric');
            app.STDNoise.ValueDisplayFormat = '%.1f';
            app.STDNoise.Value = 1;
            app.AmbNoise2_2Label = uilabel(app.ProcessParametrer);
            app.AmbNoise2_2Label.Text = 'e';
            app.STDNoise2 = uieditfield(app.ProcessParametrer, 'numeric');
            app.STDNoise2.Value = -7;
            app.AmbNoise2_2Label_2 = uilabel(app.ProcessParametrer);
            app.AmbNoise2_2Label_2.Text = 'e';
            app.PosNoise2 = uieditfield(app.ProcessParametrer, 'numeric');
            app.PosNoise2.Editable = 'off';
            app.XEditFieldLabel = uilabel(app.Filter);
            app.XEditFieldLabel.Text = 'X';
            app.AprioriX = uieditfield(app.Filter, 'numeric');
            app.AprioriX.ValueDisplayFormat = '%10.3f';
            app.AprioriX.Editable = 'off';
            app.MeasurementOption = uipanel(app.Filter);
            app.MeasurementOption.Title = 'Measurement Noise and Weight';
            app.WeightMethodLabel = uilabel(app.MeasurementOption);
            app.WeightMethodLabel.Text = 'Weight Method';
            app.MeasWeight = uidropdown(app.MeasurementOption);
            app.MeasWeight.Items = {'Equal', 'Elevation Dependent'};
            app.MeasWeight.Value = 'Elevation Dependent';
            app.MeasNoiseLabel = uilabel(app.MeasurementOption);
            app.MeasNoiseLabel.Text = 'Measurement Standart Deviation';
            app.CodeMeasLabel = uilabel(app.MeasurementOption);
            app.CodeMeasLabel.Text = 'Code Meas.';
            app.CodeMeas = uieditfield(app.MeasurementOption, 'numeric');
            app.CodeMeas.ValueDisplayFormat = '%5.3f';
            app.CodeMeas.Value = 3;
            app.PhaseMeasLabel = uilabel(app.MeasurementOption);
            app.PhaseMeasLabel.Text = 'Phase Meas.';
            app.PhaseMeas = uieditfield(app.MeasurementOption, 'numeric');
            app.PhaseMeas.ValueDisplayFormat = '%5.3f';
            app.PhaseMeas.Value = 0.003;
            app.mLabel = uilabel(app.MeasurementOption);
            app.mLabel.Text = 'm';
            app.APrioriPositionSourceDropDownLabel = uilabel(app.Filter);
            app.APrioriPositionSourceDropDownLabel.Text = 'A Priori Position Source';
            app.AprioriPosDD = uidropdown(app.Filter);
            app.AprioriPosDD.Items = {'RINEX', 'Specify'};
            app.AprioriPosDD.ValueChangedFcn = createCallbackFcn(app, @AprioriPosDDValueChanged, true);
            app.AprioriPosDD.Value = 'RINEX';
            app.YLabel = uilabel(app.Filter);
            app.YLabel.Text = 'Y';
            app.AprioriY = uieditfield(app.Filter, 'numeric');
            app.AprioriY.ValueDisplayFormat = '%10.3f';
            app.AprioriY.Editable = 'off';
            app.ZLabel = uilabel(app.Filter);
            app.ZLabel.Text = 'Z';
            app.AprioriZ = uieditfield(app.Filter, 'numeric');
            app.AprioriZ.ValueDisplayFormat = '%10.3f';
            app.AprioriZ.Editable = 'off';
            app.mLabel_2 = uilabel(app.Filter);
            app.mLabel_2.Text = 'm';
            app.Analysis = uitab(app.Tabs);
            app.Analysis.Title = 'ANALYSIS';
            app.XLabel = uilabel(app.Analysis);
            app.XLabel.Text = 'X';
            app.refX = uieditfield(app.Analysis, 'numeric');
            app.refX.ValueDisplayFormat = '%.3f';
            app.YLabel_2 = uilabel(app.Analysis);
            app.YLabel_2.Text = 'Y';
            app.refY = uieditfield(app.Analysis, 'numeric');
            app.refY.ValueDisplayFormat = '%.3f';
            app.ZLabel_2 = uilabel(app.Analysis);
            app.ZLabel_2.Text = 'Z';
            app.refZ = uieditfield(app.Analysis, 'numeric');
            app.refZ.ValueDisplayFormat = '%.3f';
            app.RFL = uilabel(app.Analysis);
            app.RFL.Text = 'Ground Truth';
            app.NEU = uibutton(app.Analysis, 'push');
            app.NEU.ButtonPushedFcn = createCallbackFcn(app, @NEUButtonPushed, true);
            app.NEU.Enable = 'off';
            app.NEU.Text = 'NEU Positioning Error';
            app.PI = uilabel(app.Analysis);
            app.PI.Text = 'Choose Plot Interval';
            app.FromEditField_2Label = uilabel(app.Analysis);
            app.FromEditField_2Label.Text = 'From';
            app.PFrom = uieditfield(app.Analysis, 'numeric');
            app.PFrom.ValueDisplayFormat = '%.0f';
            app.ToLabel = uilabel(app.Analysis);
            app.ToLabel.Text = 'To';
            app.PTo = uieditfield(app.Analysis, 'numeric');
            app.PTo.ValueDisplayFormat = '%.0f';
            app.PTo.Value = 86370;
            app.AL = uilabel(app.Analysis);
            app.AL.Text = 'Pos. Error (cm)';
            app.NorthEditFieldLabel = uilabel(app.Analysis);
            app.NorthEditFieldLabel.Text = 'North';
            app.AN = uieditfield(app.Analysis, 'numeric');
            app.AN.ValueDisplayFormat = '%3.1f';
            app.AN.Editable = 'off';
            app.EastEditFieldLabel = uilabel(app.Analysis);
            app.EastEditFieldLabel.Text = 'East';
            app.AE = uieditfield(app.Analysis, 'numeric');
            app.AE.ValueDisplayFormat = '%3.1f';
            app.AE.Editable = 'off';
            app.UpLabel = uilabel(app.Analysis);
            app.UpLabel.Text = 'Up';
            app.AU = uieditfield(app.Analysis, 'numeric');
            app.AU.ValueDisplayFormat = '%3.1f';
            app.AU.Editable = 'off';
            app.RMSL = uilabel(app.Analysis);
            app.RMSL.Text = 'RMS (cm)';
            app.NorthEditFieldLabel_2 = uilabel(app.Analysis);
            app.NorthEditFieldLabel_2.Text = 'North';
            app.RN = uieditfield(app.Analysis, 'numeric');
            app.RN.ValueDisplayFormat = '%3.1f';
            app.RN.Editable = 'off';
            app.EastEditFieldLabel_2 = uilabel(app.Analysis);
            app.EastEditFieldLabel_2.Text = 'East';
            app.RE = uieditfield(app.Analysis, 'numeric');
            app.RE.ValueDisplayFormat = '%3.1f';
            app.RE.Editable = 'off';
            app.UpLabel_2 = uilabel(app.Analysis);
            app.UpLabel_2.Text = 'Up';
            app.RU = uieditfield(app.Analysis, 'numeric');
            app.RU.ValueDisplayFormat = '%3.1f';
            app.RU.Editable = 'off';
            app.CTL = uilabel(app.Analysis);
            app.CTL.Text = 'Convergence Time (epoch)';
            app.CTLabel = uilabel(app.Analysis);
            app.CTLabel.Text = 'CT';
            app.CT = uieditfield(app.Analysis, 'numeric');
            app.CT.ValueDisplayFormat = '%.0f';
            app.CT.Editable = 'off';
            app.CalculateButton = uibutton(app.Analysis, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Enable = 'off';
            app.CalculateButton.Text = 'Calculate';
            app.HOR = uibutton(app.Analysis, 'push');
            app.HOR.ButtonPushedFcn = createCallbackFcn(app, @HORButtonPushed, true);
            app.HOR.Enable = 'off';
            app.HOR.Text = 'Horizontal Positioning Error';
            app.SNO = uibutton(app.Analysis, 'push');
            app.SNO.ButtonPushedFcn = createCallbackFcn(app, @SNOButtonPushed, true);
            app.SNO.Enable = 'off';
            app.SNO.Text = 'Satellite Number';
            app.PDOP = uibutton(app.Analysis, 'push');
            app.PDOP.ButtonPushedFcn = createCallbackFcn(app, @PDOPButtonPushed, true);
            app.PDOP.Enable = 'off';
            app.PDOP.Text = 'Dilution of Precision';
            app.ThrDPos = uibutton(app.Analysis, 'push');
            app.ThrDPos.ButtonPushedFcn = createCallbackFcn(app, @ThrDPosButtonPushed, true);
            app.ThrDPos.Enable = 'off';
            app.ThrDPos.Text = '3D Positioning Error';
            app.RecClkEst = uibutton(app.Analysis, 'push');
            app.RecClkEst.ButtonPushedFcn = createCallbackFcn(app, @RecClkEstButtonPushed, true);
            app.RecClkEst.Enable = 'off';
            app.RecClkEst.Text = 'Receiver Clock Estimation';
            app.TZD = uibutton(app.Analysis, 'push');
            app.TZD.ButtonPushedFcn = createCallbackFcn(app, @TZDButtonPushed, true);
            app.TZD.Enable = 'off';
            app.TZD.Text = 'Tropospheric Zenith Total Delay';
            app.MainLabel = uilabel(app.Main);
            app.MainLabel.Text = 'PPPH';
            app.RunButton = uibutton(app.Main, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = 'play_button-512.png';
            app.RunButton.Text = 'RUN';
            app.SaveConfigurationButton = uibutton(app.Main, 'push');
            app.SaveConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigurationButtonPushed, true);
            app.SaveConfigurationButton.Text = 'Save Configuration';
            app.DefaultConfigurationButton = uibutton(app.Main, 'push');
            app.DefaultConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultConfigurationButtonPushed, true);
            app.DefaultConfigurationButton.Text = 'Default Configuration';
            app.EpochIntervalsecondofdayLabel = uilabel(app.Main);
            app.EpochIntervalsecondofdayLabel.Text = 'Epoch Interval (second of day)';
            app.FromEditFieldLabel = uilabel(app.Main);
            app.FromEditFieldLabel.Text = 'From';
            app.FromEditField = uieditfield(app.Main, 'numeric');
            app.FromEditField.ValueDisplayFormat = '%.0f';
            app.ToEditFieldLabel = uilabel(app.Main);
            app.ToEditFieldLabel.Text = 'To';
            app.ToEditField = uieditfield(app.Main, 'numeric');
            app.ToEditField.ValueDisplayFormat = '%.0f';
            app.ToEditField.Value = 86370;
            app.LoadSavedConfigurationButton = uibutton(app.Main, 'push');
            app.LoadSavedConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSavedConfigurationButtonPushed, true);
            app.LoadSavedConfigurationButton.Text = 'Load Saved Configuration';
            % Show the figure after all components are created
            app.Main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PPPH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Main)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Main)
        end
    end
end